classdef MagneticFieldCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        InputsTab                    matlab.ui.container.Tab
        PhaseAPanel                  matlab.ui.container.Panel
        LineCurrentEditField_2Label  matlab.ui.control.Label
        LineCurrentA                 matlab.ui.control.NumericEditField
        PhaseAngleEditField_2Label   matlab.ui.control.Label
        PhaseAngleA                  matlab.ui.control.NumericEditField
        XEditField_2Label            matlab.ui.control.Label
        X_A                          matlab.ui.control.NumericEditField
        YEditField_2Label            matlab.ui.control.Label
        Y_A                          matlab.ui.control.NumericEditField
        PhaseCPanel                  matlab.ui.container.Panel
        LineCurrentEditField_3Label  matlab.ui.control.Label
        LineCurrentC                 matlab.ui.control.NumericEditField
        PhaseAngleEditField_3Label   matlab.ui.control.Label
        PhaseAngleC                  matlab.ui.control.NumericEditField
        XEditField_3Label            matlab.ui.control.Label
        X_C                          matlab.ui.control.NumericEditField
        YEditField_3Label            matlab.ui.control.Label
        Y_C                          matlab.ui.control.NumericEditField
        PhaseBPanel                  matlab.ui.container.Panel
        LineCurrentEditField_4Label  matlab.ui.control.Label
        LineCurrentB                 matlab.ui.control.NumericEditField
        PhaseAngleEditField_4Label   matlab.ui.control.Label
        PhaseAngleB                  matlab.ui.control.NumericEditField
        XEditField_4Label            matlab.ui.control.Label
        X_B                          matlab.ui.control.NumericEditField
        YEditField_4Label            matlab.ui.control.Label
        Y_B                          matlab.ui.control.NumericEditField
        CalculateButton              matlab.ui.control.Button
        MetersfromCenterlineEditFieldLabel  matlab.ui.control.Label
        HorizontalRange              matlab.ui.control.NumericEditField
        GraphTab                     matlab.ui.container.Tab
        MagneticFluxGraph            matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function Btot = MagneticFluxMagnitude(app, xPos)
            
            % in phase components
            %left most conductor
            Bxin_a = MagneticFluxXInPhase(app, app.LineCurrentA.Value, degtorad(app.PhaseAngleA.Value), xPos + app.X_A.Value, app.Y_A.Value);
            Byin_a = MagneticFluxYInPhase(app, app.LineCurrentA.Value, degtorad(app.PhaseAngleA.Value), xPos + app.X_A.Value, app.Y_A.Value);
            
            %central
            Bxin_b = MagneticFluxXInPhase(app, app.LineCurrentB.Value, degtorad(app.PhaseAngleB.Value), xPos + app.X_B.Value, app.Y_B.Value);
            Byin_b = MagneticFluxYInPhase(app, app.LineCurrentB.Value, degtorad(app.PhaseAngleB.Value), xPos + app.X_B.Value, app.Y_B.Value);
            
            %right 
            Bxin_c = MagneticFluxXInPhase(app, app.LineCurrentC.Value, degtorad(app.PhaseAngleC.Value), xPos + app.X_C.Value, app.Y_C.Value);
            Byin_c = MagneticFluxYInPhase(app, app.LineCurrentC.Value, degtorad(app.PhaseAngleC.Value), xPos + app.X_C.Value, app.Y_C.Value);
            
            % out of phase components
            Bxout_a = MagneticFluxXOutPhase(app, app.LineCurrentA.Value, degtorad(app.PhaseAngleA.Value), xPos + app.X_A.Value, app.Y_A.Value);
            Byout_a = MagneticFluxYOutPhase(app, app.LineCurrentA.Value, degtorad(app.PhaseAngleA.Value), xPos + app.X_A.Value, app.Y_A.Value);
            
            %central
            Bxout_b = MagneticFluxXOutPhase(app, app.LineCurrentB.Value, degtorad(app.PhaseAngleB.Value), xPos + app.X_B.Value, app.Y_B.Value);
            Byout_b = MagneticFluxYOutPhase(app, app.LineCurrentB.Value, degtorad(app.PhaseAngleB.Value), xPos + app.X_B.Value, app.Y_B.Value);
            
            %right 
            Bxout_c = MagneticFluxXOutPhase(app, app.LineCurrentC.Value, degtorad(app.PhaseAngleC.Value), xPos + app.X_C.Value, app.Y_C.Value);
            Byout_c = MagneticFluxYOutPhase(app, app.LineCurrentC.Value, degtorad(app.PhaseAngleC.Value), xPos + app.X_C.Value, app.Y_C.Value);
            
            
            Bxin = Bxin_a + Bxin_b + Bxin_c;
            Byin = Byin_a + Byin_b + Byin_c;
            Bxout = Bxout_a + Bxout_b + Bxout_c;
            Byout = Byout_a + Byout_b + Byout_c;
            
            Btot = sqrt(Bxin^2 + Byin^2 + Bxout^2 + Byout^2);
         end
        
        function Byin = MagneticFluxYInPhase(~, I, phase, X, Y)
            Iphase = I*cos(phase);
            permeability = 4*pi*10.^-7;
            
            Byin = (permeability * X * Iphase)/(2*pi*(X.^2 + Y.^2));
        end
        
        function Bxin = MagneticFluxXInPhase(~, I, phase, X, Y)
            Iphase = I*cos(phase);
            permeability = 4*pi*10^-7;
            
            Bxin = -(permeability * Y * Iphase)/(2*pi*(X^2 + Y^2));
        end
        
        function Bxout = MagneticFluxXOutPhase(~, I, phase, X, Y)
            Iphase = I*sin(phase);
            permeability = 4*pi*10^-7;
            
            Bxout = -(permeability * Y * Iphase)/(2*pi*(X^2 + Y^2));
        end
        
        function Byout = MagneticFluxYOutPhase(~, I, phase, X, Y)
            Iphase = I*sin(phase);
            permeability = 4*pi*10^-7;
            
            Byout = (permeability * X * Iphase)/(2*pi*(X^2 + Y^2));
        end
        
    end


    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            Btot= zeros(1,4000);
            
            xPosition = linspace(-app.HorizontalRange.Value, app.HorizontalRange.Value, 4000);
            
            for n = 1:4000
                Btot(n) = MagneticFluxMagnitude(app, xPosition(n));
            end
            app.MagneticFluxGraph.YAxisLocation = 'origin';
            plot(app.MagneticFluxGraph, xPosition, Btot);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.InputsTab.Units = 'pixels';
            app.PhaseAPanel = uipanel(app.InputsTab);
            app.PhaseAPanel.Title = 'Phase A';
            app.LineCurrentEditField_2Label = uilabel(app.PhaseAPanel);
            app.LineCurrentEditField_2Label.Text = 'Line Current';
            app.LineCurrentA = uieditfield(app.PhaseAPanel, 'numeric');
            app.LineCurrentA.Value = 350;
            app.PhaseAngleEditField_2Label = uilabel(app.PhaseAPanel);
            app.PhaseAngleEditField_2Label.Text = 'Phase Angle';
            app.PhaseAngleA = uieditfield(app.PhaseAPanel, 'numeric');
            app.PhaseAngleA.Value = 120;
            app.XEditField_2Label = uilabel(app.PhaseAPanel);
            app.XEditField_2Label.Text = 'X';
            app.X_A = uieditfield(app.PhaseAPanel, 'numeric');
            app.YEditField_2Label = uilabel(app.PhaseAPanel);
            app.YEditField_2Label.Text = 'Y';
            app.Y_A = uieditfield(app.PhaseAPanel, 'numeric');
            app.Y_A.Value = -10;
            app.PhaseCPanel = uipanel(app.InputsTab);
            app.PhaseCPanel.Title = 'Phase C';
            app.LineCurrentEditField_3Label = uilabel(app.PhaseCPanel);
            app.LineCurrentEditField_3Label.Text = 'Line Current';
            app.LineCurrentC = uieditfield(app.PhaseCPanel, 'numeric');
            app.LineCurrentC.Value = 350;
            app.PhaseAngleEditField_3Label = uilabel(app.PhaseCPanel);
            app.PhaseAngleEditField_3Label.Text = 'Phase Angle';
            app.PhaseAngleC = uieditfield(app.PhaseCPanel, 'numeric');
            app.PhaseAngleC.Value = -120;
            app.XEditField_3Label = uilabel(app.PhaseCPanel);
            app.XEditField_3Label.Text = 'X';
            app.X_C = uieditfield(app.PhaseCPanel, 'numeric');
            app.YEditField_3Label = uilabel(app.PhaseCPanel);
            app.YEditField_3Label.Text = 'Y';
            app.Y_C = uieditfield(app.PhaseCPanel, 'numeric');
            app.Y_C.Value = -10;
            app.PhaseBPanel = uipanel(app.InputsTab);
            app.PhaseBPanel.Title = 'Phase B';
            app.LineCurrentEditField_4Label = uilabel(app.PhaseBPanel);
            app.LineCurrentEditField_4Label.Text = 'Line Current';
            app.LineCurrentB = uieditfield(app.PhaseBPanel, 'numeric');
            app.LineCurrentB.Value = 350;
            app.PhaseAngleEditField_4Label = uilabel(app.PhaseBPanel);
            app.PhaseAngleEditField_4Label.Text = 'Phase Angle';
            app.PhaseAngleB = uieditfield(app.PhaseBPanel, 'numeric');
            app.XEditField_4Label = uilabel(app.PhaseBPanel);
            app.XEditField_4Label.Text = 'X';
            app.X_B = uieditfield(app.PhaseBPanel, 'numeric');
            app.YEditField_4Label = uilabel(app.PhaseBPanel);
            app.YEditField_4Label.Text = 'Y';
            app.Y_B = uieditfield(app.PhaseBPanel, 'numeric');
            app.Y_B.Value = -10;
            app.CalculateButton = uibutton(app.InputsTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MetersfromCenterlineEditFieldLabel = uilabel(app.InputsTab);
            app.MetersfromCenterlineEditFieldLabel.Text = 'Meters from Centerline';
            app.HorizontalRange = uieditfield(app.InputsTab, 'numeric');
            app.HorizontalRange.Limits = [0 Inf];
            app.HorizontalRange.Value = 200;
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.GraphTab.Units = 'pixels';
            app.MagneticFluxGraph = uiaxes(app.GraphTab);
            title(app.MagneticFluxGraph, 'Magnitude of Magnetic Flux Density From Transmission Line');
            xlabel(app.MagneticFluxGraph, 'Horizontal Distance (m)');
            ylabel(app.MagneticFluxGraph, 'Magnetic Flux Magnitude (T)');
        end
    end

    methods (Access = public)

        % Construct app
        function app = MagneticFieldCalculator()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end